classdef DREAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                        matlab.ui.Figure
        DecodeRhythmsoftheBrainSystemLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        ShowDataImg                   matlab.ui.control.UIAxes
        PlotPanel                     matlab.ui.container.Panel
        Inputset                      matlab.ui.container.Panel
        TRLabel                       matlab.ui.control.Label
        SelectSubjectsListLabel       matlab.ui.control.Label
        SelectWorkDirectoryLabel      matlab.ui.control.Label
        DataDirLabel                  matlab.ui.control.Label
        choWorkDir                    matlab.ui.control.Button
        choSublist                    matlab.ui.control.Button
        TR                            matlab.ui.control.NumericEditField
        DataDir                       matlab.ui.control.EditField
        ShowWorkDir                   matlab.ui.control.Label
        ShowSublist                   matlab.ui.control.Label
        DataListBox                   matlab.ui.control.ListBox
        Remove                        matlab.ui.control.Button
        Run                           matlab.ui.control.Button
        ChooseDataTypeDropDownLabel   matlab.ui.control.Label
        ChooseDataType                matlab.ui.control.DropDown
        ChooseDataStyleDropDownLabel  matlab.ui.control.Label
        ChooseDataStyle               matlab.ui.control.DropDown
        BKG                           matlab.ui.control.UIAxes
        Enter                         matlab.ui.control.Button
        DLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        eLabel                        matlab.ui.control.Label
        aLabel                        matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
    end

    properties (Access = public)
        workdir;
        sub_list;
        datatype;
        datastyle;
        data = '/';
        files;
        tr;
        DREAMPATH = fileparts(which('DREAM.m'));
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Figure.Position = [100,100,800,500];
            app.Inputset.Visible = 'off';
            app.Enter.Position = [0,0,800,500];
            app.ShowDataImg.Position = [50,50,900,600];
            app.Panel.Visible= 'off';
            
        end

        % Button pushed function: Enter
        function EnterPushed(app, event)
            app.Figure.Position = [100,100,1500,900];
            app.Enter.Visible = 'off';
            app.Inputset.Visible = 'on';
            app.Figure.Resize = 'on';
            app.Panel.Visible= 'on';
            
        end

        % Button pushed function: choWorkDir
        function choWorkDirButtonPushed(app, event)
            app.ShowDataImg.reset;
            app.ShowDataImg.XColor = 'none';
            app.ShowDataImg.YColor = 'none';
            app.ShowDataImg.Color = [0.94 0.94 0.94];
            plot(app.ShowDataImg,0);
            title(app.ShowDataImg,' ');
            
            app.ShowSublist.Text = '';
            app.ShowWorkDir.Text = '';
            [pathname] = uigetdir;
            if pathname
                app.workdir = pathname;
                set(app.ShowWorkDir,'Text',pathname);
            end
            style = get(app.ChooseDataStyle,'Value');
            app.datastyle =char(style);
            if (strfind(app.datastyle,'BIDS'))
                cd(app.workdir);
                sub_all = dir('sub*');
                sublist_all = {sub_all.name};
                for i =1:length(sublist_all)
                    if(strcmp(sublist_all(i),'sub.list'))
                        sublist_all(i)=[];
                    end
                end
                sublist_all = sublist_all';
                
                fid=fopen('sub.list','w');
                for i=1:size(sublist_all,1)
                    a = sublist_all(i);
                    a = cell2mat(a);
                    fprintf(fid,'%s\n',a);
                end
                fclose(fid);
                
                app.data = 'func';
                app.sub_list = [app.workdir , '/' ,'sub.list'];
                app.SelectSubjectsListLabel.Visible = 'off';
                app.choSublist.Visible="off";
                app.DataDirLabel.Visible = "off";
                app.DataDir.Visible = "off";
            else
                app.data = '/';
                app.SelectSubjectsListLabel.Visible = 'on';
                app.choSublist.Visible="on";
                app.DataDirLabel.Visible = "on";
                app.DataDir.Visible = "on";
            end
        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            app.Panel.Scrollable = 'on';
            if (strfind(app.datatype,'NIFTI'))
                app.ShowDataImg.Visible = 'on';
                feature memstats;
                mem = ans;
                if (mem < 1e+20)
                    DREAM_FreqCalc_IMG(app.tr,app.workdir,app.sub_list,app.data,app.files);
                else if(mem > 1e+40)
                        DREAM_FreqCalc_IMGdp(app.tr,app.workdir,app.sub_list,app.data,app.files);
                    else
                        DREAM_FreqCalc_IMGd(app.tr,app.workdir,app.sub_list,app.data,app.files)
                    end
                    
                end
                
                app.ShowDataImg.Position = [0,0,1,1];
                
                csv = importdata('freqbands.csv');
                [m,n] = size(csv);
                
                data_all = dir([pwd,'/*.nii.gz']);
                data_all = {data_all.name};
                for tem = 1:length(data_all)
                    data = data_all{1,tem};
                    
                    if length(data_all)>8
                        h = 100*length(data_all);
                        app.PlotPanel.Visible = 'on';
                        app.PlotPanel.Position = [1 1 950 h];
                    else
                        app.PlotPanel.Visible = 'on';
                        app.PlotPanel.Position = [1 1 950 800];
                    end
                    ax1 = subplot(length(data_all),1,tem,'Parent',app.PlotPanel);
                    plotsub = load_nifti(data);
                    X= size(plotsub.vol,1);
                    Y= size(plotsub.vol,2);
                    Z = size(plotsub.vol,3);
                    num_samples = size(plotsub.vol,4);
                    plotsub_vol= reshape(plotsub.vol,X*Y*Z,num_samples);
                    
                    plot(ax1,plotsub_vol(100,:));
                    
                    C = [num2str(csv(tem,1)),'-',num2str(csv(tem,n)),'Hz'];
                    title(ax1,C);
                    
                end
            else if(strfind(app.datatype,'1D'))
                    app.ShowDataImg.Visible = 'on';
                    DREAM_FreqCalc_FD(app.tr, app.workdir,app.sub_list,app.data,app.files);
                    app.ShowDataImg.Position = [0,0,1,1];
                    
                    csv = importdata('freqbands.csv');
                    [m,n] = size(csv);
                    
                    data_all = dir([pwd,'/*.1D']);
                    data_all = {data_all.name};
                    for tem = 1:length(data_all)
                        data = data_all{1,tem};
                        
                        if length(data_all)>8
                            h = 100*length(data_all);
                            app.PlotPanel.Visible = 'on';
                            app.PlotPanel.Position = [1 1 950 h];
                        else
                            app.PlotPanel.Visible = 'on';
                            app.PlotPanel.Position = [1 1 950 800];
                        end
                        
                        ax1 = subplot(length(data_all),1,tem,'Parent',app.PlotPanel);
                        plot(ax1,importdata(data));
                        
                        C = [num2str(csv(tem,1)),'-',num2str(csv(tem,n)),'Hz'];
                        title(ax1,C);
                        
                    end
                else if(strfind(app.datatype,'EEG'))
                        app.ShowDataImg.Visible = 'on';
                        DREAM_FreqCalc_EEG(app.tr,app.workdir,app.sub_list,app.data,app.files);
                        app.ShowDataImg.Position = [0,0,1,1];
                        
                        csv = importdata('freqbands.csv');
                        [m,n] = size(csv);
                        
                        data_all = dir([pwd,'/*.vhdr']);
                        data_all = {data_all.name};
                        for tem = 1:length(data_all)
                            data = data_all{1,tem};
                            [EEG,com] = pop_loadbv(pwd,data);
                            plotdata = EEG.data;
                            n = size(EEG.data,1);
                            N = size(EEG.data,2);
                            for i = 1 : n
                                channame(i,1) = string(EEG.chanlocs(i).labels);
                            end
                            i = find(strcmp(channame, 'Cz'));
                            
                            if length(data_all)>8
                                h = 100*length(data_all);
                                app.PlotPanel.Visible = 'on';
                                app.PlotPanel.Position = [1 1 950 h];
                            else
                                app.PlotPanel.Visible = 'on';
                                app.PlotPanel.Position = [1 1 950 800];
                            end
                            
                            ax1 = subplot(length(data_all),1,tem,'Parent',app.PlotPanel);
                            plot(ax1,EEG.data(i,:));
                            
                            C = [num2str(csv(tem,1)),'-',num2str(csv(tem,2)),'Hz'];
                            title(ax1,C);
                        end
                    end
                end
            end
            
            
        end

        % Button pushed function: choSublist
        function choSublistButtonPushed(app, event)
            app.ShowDataImg.reset;
            app.ShowDataImg.XColor = 'none';
            app.ShowDataImg.YColor = 'none';
            app.ShowDataImg.Color = [0.94 0.94 0.94];
            plot(app.ShowDataImg,0);
            title(app.ShowDataImg,' ');
            
            app.ShowDataImg.Visible = 'off';
            [filename,filepath] = uigetfile('*.list','select a sublist');
            app.sub_list = fullfile(filepath,filename);
            set(app.ShowSublist,'Text',app.sub_list);
            style = get(app.ChooseDataStyle,'Value');
            app.datastyle =char(style);
            if (strfind(app.datastyle,'BIDS'))
                app.sub_list = [app.workdir , '/' ,'sub.list'];
            end
        end

        % Value changed function: TR
        function TRValueChanged(app, event)
            app.ShowDataImg.reset;
            app.ShowDataImg.XColor = 'none';
            app.ShowDataImg.YColor = 'none';
            app.ShowDataImg.Color = [0.94 0.94 0.94];
            plot(app.ShowDataImg,0);
            title(app.ShowDataImg,' ');
            
            app.ShowDataImg.Visible = 'on';
            app.tr = app.TR.Value;
            app.data = app.DataDir.Value;
            
            sublist_all = importdata(app.sub_list);
            sub = char(sublist_all(1,1));
            val = get(app.ChooseDataType,'Value');
            app.datatype =char(val);
            style = get(app.ChooseDataStyle,'Value');
            app.datastyle =char(style);
            if (strfind(app.datatype,'NIFTI'))
                sublist_all = importdata(app.sub_list);
                sub = char(sublist_all(1,1));
                if(strfind(app.datastyle,'BIDS'))
                    app.data = 'func';
                else
                    app.data = app.DataDir.Value;
                end
                
                files = dir([app.workdir,'/' sub ,'/',app.data ,'/*.nii.gz']);
            else if(strfind(app.datatype,'1D'))
                    files = dir([app.workdir,'/' sub ,'/',app.data ,'/*.1D']);
                else if(strfind(app.datatype,'EEG'))
                        files = dir([app.workdir,'/',sub,'/',app.data,'/*.vhdr']);
                    end
                end
            end
            app.files = {files.name};
            set(app.DataListBox,'Items',app.files);
            
        end

        % Value changed function: DataListBox
        function DataListBoxValueChanged(app, event)
            app.ShowDataImg.reset;
            app.PlotPanel.Position = [1 1 1 1];
            plot(app.ShowDataImg,0);
            value = app.DataListBox.Value;
            app.data = app.DataDir.Value;
            app.Panel.Scrollable = 'off';
            app.ShowDataImg.Position = [100,100,700,500];
            if ~isempty(app.files)
                sublist_all = importdata(app.sub_list);
                style = get(app.ChooseDataStyle,'Value');
                app.datastyle =char(style);
                if(strfind(app.datastyle,'BIDS'))
                    app.data = 'func';
                else
                    app.data = app.DataDir.Value;
                end
                
                sub = char(sublist_all(1,1));
                
                str=get(app.DataListBox,'Items');
                v=get(app.DataListBox,'value');
                [bool idx]= ismember(v,str);
                if idx~=0
                    plotsub = app.files{idx};
                    cd([app.workdir ,'/' sub ,'/' app.data ]);
                    if (strfind(app.datatype,'NIFTI'))
                        
                        plotsub = load_nifti(plotsub);
                        X= size(plotsub.vol,1);
                        Y= size(plotsub.vol,2);
                        Z = size(plotsub.vol,3);
                        num_samples = size(plotsub.vol,4);
                        plotsub_vol= reshape(plotsub.vol,X*Y*Z,num_samples);
                        app.Panel.Visible = 'on';
                        app.ShowDataImg.Visible = 'on';
                        
                        plot(app.ShowDataImg,plotsub_vol(100,:));
                        
                        N = length(plotsub_vol);
                        fmax = 1/(2*app.tr);
                        fmin = 1/(N*app.tr/2);
                        C = [num2str(fmin),'-',num2str(fmax),'Hz'];
                        title(app.ShowDataImg,C);
                    else if(strfind(app.datatype,'1D'))
                            
                            plotsub_vol = importdata(plotsub);
                            app.Panel.Visible = 'on';
                            app.ShowDataImg.Visible = 'on';
                            plot(app.ShowDataImg,plotsub_vol);
                            
                            N = length(plotsub_vol);
                            fmax = 1/(2*app.tr);
                            fmin = 1/(N*app.tr/2);
                            if rem(N,2)==0
                                fnum = N/2;
                            else
                                fnum = (N+1)/2;
                            end
                            freq = linspace(0,fmax,fnum+1);
                            tmpidx = find(freq<=fmin);
                            frmin = freq(tmpidx(end)+4);
                            
                            C = [num2str(frmin),'-',num2str(fmax),'Hz'];
                            title(app.ShowDataImg,C);
                            
                            
                        else if(strfind(app.datatype,'EEG'))
                                
                                [EEG,com] = pop_loadbv([app.workdir ,'/' sub ,'/' app.data,],plotsub);
                                plotdata = EEG.data;
                                
                                n = size(EEG.data,1);
                                N = size(EEG.data,2);
                                ystep = zeros(1,n);
                                for i = 1 : n
                                    channame(i,1) = string(EEG.chanlocs(i).labels);
                                    ystep(1,i) = (i-1)*50;
                                end
                                for i = 1 : n
                                    
                                    set(app.ShowDataImg,'ytick',ystep,'yticklabel',channame);
                                    plot(app.ShowDataImg,plotdata(i,:)/10+(n-i+1)*50);
                                    hold (app.ShowDataImg,'on');
                                end
                                fmax = 1/(2*app.tr);
                                fmin = 1/(N*app.tr/2);
                                if rem(N,2)==0
                                    fnum = N/2;
                                else
                                    fnum = (N+1)/2;
                                end
                                freq = linspace(0,fmax,fnum+1);
                                tmpidx = find(freq<=fmin);
                                frmin = freq(tmpidx(end)+4);
                                
                                C = [num2str(frmin),'-',num2str(fmax),'Hz'];
                                title(app.ShowDataImg,C);
                            end
                        end
                    end
                end
            end
        end

        % Value changed function: DataDir
        function DataDirValueChanged(app, event)
            app.ShowDataImg.reset;
            app.ShowDataImg.XColor = 'none';
            app.ShowDataImg.YColor = 'none';
            app.ShowDataImg.Color = [0.94 0.94 0.94];
            plot(app.ShowDataImg,0);
            title(app.ShowDataImg,' ');
            
            app.data = app.DataDir.Value;
            app.ShowDataImg.Position = [100,100,700,500];
            
            sublist_all = importdata(app.sub_list);
            sub = char(sublist_all(1,1));
            app.tr = app.TR.Value;
            val = get(app.ChooseDataType,'Value');
            app.datatype =char(val);
            style = get(app.ChooseDataStyle,'Value');
            app.datastyle =char(style);
            if (strfind(app.datatype,'NIFTI'))
                sublist_all = importdata(app.sub_list);
                sub = char(sublist_all(1,1));
                
                files = dir([app.workdir ,'/' sub ,'/' app.data ,'/*.nii.gz']);
            else if(strfind(app.datatype,'1D'))
                    files = dir([app.workdir ,'/' sub ,'/' app.data ,'/*.1D']);
                else if(strfind(app.datatype,'EEG'))
                        files = dir([app.workdir ,'/' sub ,'/' app.data ,'/*.vhdr']);
                    end
                end
            end
            app.files = {files.name};
            set(app.DataListBox,'Items',app.files);
            
            if ~isempty(app.files)
                sublist_all = importdata(app.sub_list);
                if (strfind(app.datastyle,'BIDS'))
                    subbids = char(sublist_all(1,1));
                    sub = ['Sub-',subbids];
                else
                    sub = char(sublist_all(1,1));
                end
                str=get(app.DataListBox,'Items');
                v=get(app.DataListBox,'value');
                [bool idx]= ismember(v,str);
                if ~isempty(idx)
                    plotsub = app.files{idx};
                    cd([app.workdir ,'/' sub ,'/' app.data ]);
                    if (strfind(app.datatype,'NIFTI'))
                        
                        plotsub = load_nifti(plotsub);
                        X= size(plotsub.vol,1);
                        Y= size(plotsub.vol,2);
                        Z = size(plotsub.vol,3);
                        num_samples = size(plotsub.vol,4);
                        plotsub_vol= reshape(plotsub.vol,X*Y*Z,num_samples);
                        app.Panel.Visible = 'on';
                        app.ShowDataImg.Visible = 'on';
                        
                        plot(app.ShowDataImg,plotsub_vol(100,:));
                        
                    else if(strfind(app.datatype,'1D'))
                            
                            plotsub_vol = LFCD_IPN_computeMC(plotsub);
                            app.Panel.Visible = 'on';
                            app.ShowDataImg.Visible = 'on';
                            plot(app.ShowDataImg,plotsub_vol);
                            
                            N = length(plotsub_vol);
                            fmax = 1/(2*app.tr);
                            fmin = 1/(N*app.tr/2);
                            
                            C = [fmin,'-',fmax,'Hz'];
                            title(app.ShowDataImg,C);
                            
                        else if(strfind(app.datatype,'EEG'))
                                [EEG,com] = pop_loadbv([app.workdir ,'/' sub ,'/' app.data,],plotsub);
                                app.Panel.Visible = 'off';
                                app.ShowDataImg.Visible = 'off';
                                image(app.ShowDataImg,eegplot(EEG.data));
                                
                            end
                        end
                    end
                end
            end
            
        end

        % Button pushed function: Remove
        function RemoveButtonPushed(app, event)
            str=get(app.DataListBox,'Items');
            v=get(app.DataListBox,'value');
            [bool idx]= ismember(v,str);
            str(idx)=[];
            set(app.DataListBox,'Items',str);
            app.files = str;
            
        end

        % Value changed function: ChooseDataType
        function ChooseDataTypeValueChanged(app, event)
            value = app.ChooseDataType.Value;
            app.ShowDataImg.reset;
            app.ShowDataImg.XColor = 'none';
            app.ShowDataImg.YColor = 'none';
            app.ShowDataImg.Color = [0.94 0.94 0.94];
            plot(app.ShowDataImg,0);
            title(app.ShowDataImg,' ');
            
            set(app.DataListBox,'Items',{});
            app.PlotPanel.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure;
            app.Figure.Name = 'Dream';
            app.DecodeRhythmsoftheBrainSystemLabel = uilabel(app.Figure);
            app.DecodeRhythmsoftheBrainSystemLabel.Text = 'Decode Rhythms of the Brain System';
            app.Panel = uipanel(app.Figure);
            app.ShowDataImg = uiaxes(app.Panel);
            app.ShowDataImg.XTick = [];
            app.ShowDataImg.YTick = [];
            app.ShowDataImg.Visible = 'off';
            app.PlotPanel = uipanel(app.Panel);
            app.PlotPanel.Visible = 'off';
            app.Inputset = uipanel(app.Figure);
            app.TRLabel = uilabel(app.Inputset);
            app.TRLabel.Text = 'TR';
            app.SelectSubjectsListLabel = uilabel(app.Inputset);
            app.SelectSubjectsListLabel.Text = 'Select Subjects List';
            app.SelectWorkDirectoryLabel = uilabel(app.Inputset);
            app.SelectWorkDirectoryLabel.Text = 'Select Work Directory';
            app.DataDirLabel = uilabel(app.Inputset);
            app.DataDirLabel.Text = 'Data Dir';
            app.choWorkDir = uibutton(app.Inputset, 'push');
            app.choWorkDir.ButtonPushedFcn = createCallbackFcn(app, @choWorkDirButtonPushed, true);
            app.choWorkDir.Tooltip = {'choose '};
            app.choWorkDir.Text = '...';
            app.choSublist = uibutton(app.Inputset, 'push');
            app.choSublist.ButtonPushedFcn = createCallbackFcn(app, @choSublistButtonPushed, true);
            app.choSublist.Text = '....';
            app.TR = uieditfield(app.Inputset, 'numeric');
            app.TR.ValueChangedFcn = createCallbackFcn(app, @TRValueChanged, true);
            app.DataDir = uieditfield(app.Inputset, 'text');
            app.DataDir.ValueChangedFcn = createCallbackFcn(app, @DataDirValueChanged, true);
            app.ShowWorkDir = uilabel(app.Inputset);
            app.ShowWorkDir.Text = '';
            app.ShowSublist = uilabel(app.Inputset);
            app.ShowSublist.Text = '';
            app.DataListBox = uilistbox(app.Inputset);
            app.DataListBox.Items = {};
            app.DataListBox.ValueChangedFcn = createCallbackFcn(app, @DataListBoxValueChanged, true);
            app.DataListBox.Value = {};
            app.Remove = uibutton(app.Inputset, 'push');
            app.Remove.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.Remove.Text = {'Remove'; ''};
            app.Run = uibutton(app.Inputset, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = 'Decode';
            app.ChooseDataTypeDropDownLabel = uilabel(app.Inputset);
            app.ChooseDataTypeDropDownLabel.Text = 'Choose Data Type';
            app.ChooseDataType = uidropdown(app.Inputset);
            app.ChooseDataType.Items = {'-----------', 'NIFTI', '1D', 'EEG'};
            app.ChooseDataType.ValueChangedFcn = createCallbackFcn(app, @ChooseDataTypeValueChanged, true);
            app.ChooseDataType.Value = '-----------';
            app.ChooseDataStyleDropDownLabel = uilabel(app.Inputset);
            app.ChooseDataStyleDropDownLabel.Text = 'Choose Data Style';
            app.ChooseDataStyle = uidropdown(app.Inputset);
            app.ChooseDataStyle.Items = {'-----------', 'BIDS', 'CCS'};
            app.ChooseDataStyle.Value = '-----------';
            app.BKG = uiaxes(app.Figure);
            app.BKG.YLim = [0 1];
            app.BKG.ALim = [0 1];
            app.BKG.XTick = [];
            app.BKG.YTick = [];
            app.BKG.ZTick = [];
            app.BKG.Visible = 'off';
            app.Enter = uibutton(app.Figure, 'push');
            app.Enter.ButtonPushedFcn = createCallbackFcn(app, @EnterPushed, true);
            app.Enter.Icon = 'DREAM-screen.jpg';
            app.Enter.Text = '';
            app.DLabel = uilabel(app.Figure);
            app.DLabel.Text = 'D';
            app.RLabel = uilabel(app.Figure);
            app.RLabel.Text = 'R';
            app.eLabel = uilabel(app.Figure);
            app.eLabel.Text = 'e';
            app.aLabel = uilabel(app.Figure);
            app.aLabel.Text = 'a';
            app.mLabel = uilabel(app.Figure);
            app.mLabel.Text = 'm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DREAM

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end